classdef Spiculation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BoundarySpiculationUIFigure  matlab.ui.Figure
        UndoonceButton               matlab.ui.control.Button
        HeightEditField              matlab.ui.control.EditField
        HeightEditFieldLabel         matlab.ui.control.Label
        WidthEditField               matlab.ui.control.EditField
        WidthEditFieldLabel          matlab.ui.control.Label
        ApplytocurrentButton         matlab.ui.control.Button
        ApplytooriginalButton        matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        CenterSlider                 matlab.ui.control.Slider
        CenterSliderLabel            matlab.ui.control.Label
        center_value                 matlab.ui.control.NumericEditField
    end


    properties (Access = private)

        CallingApp   % Main app object

    end

    methods (Access = private)

        % main function
        function Spiculation_gen(app,from_org)

            if from_org  % apply to original?
                obd = evalin("base","obd");
                x = obd(:,1);
                y = obd(:,2);
            else
                bd = evalin("base","bd");
                x = bd(:,1);
                y = bd(:,2);
            end

            % get cenrtoid
            centerX = round(mean(x));
            centerY = round(mean(y));
            % convert to polar coordinate
            [theta,rho] = cart2pol(x-centerX,y-centerY);
            theta = theta + pi; % [-pi pi]->[0 2pi]
            %-----------theta in [0 2pi]---- begin

            location = (app.center_value.Value/360)*(2*pi); % 360->2pi
            out_rho = hat_rho(rho,theta,location,str2num(app.HeightEditField.Value),str2num(app.WidthEditField.Value));

            %------------------------------- end
            % convert back to Cartesian coordinate
            [newx, newy] = pol2cart(theta-pi,out_rho); % [0 2pi]->[-pi pi]
            Xint = round(newx) + centerX;
            Yint = round(newy) + centerY;
            bd = [Xint,Yint];

            try % save last-time "bd" for _Undo_ if it exists
                old_bd = evalin("base","bd");
                if ~isequal(old_bd,bd)
                    app.UndoonceButton.Enable = "on";
                    assignin("base","old_bd",old_bd); % public data
                else
                    % disp "bd==old_bd"
                end
            end

            assignin("base","bd",bd); % write to replace public data bd

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;

            app.CenterSlider.Limits = [0, 360-1];
            app.CenterSlider.Value = 130; % init Center=130
            app.center_value.Value = round(app.CenterSlider.Value);

            app.UndoonceButton.Enable="off";
        end

        % Value changing function: CenterSlider
        function CenterSliderValueChanging(app, event)

        end

        % Value changed function: CenterSlider
        function CenterSliderValueChanged(app, event)

            app.center_value.Value = round(app.CenterSlider.Value);
        end

        % Value changed function: center_value
        function center_valueValueChanged(app, event)

            app.CenterSlider.Value = app.center_value.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ApplytooriginalButton
        function ApplytooriginalButtonPushed(app, event)
            Spiculation_gen(app,true)
            bd = evalin("base","bd");
            updateimage(app.CallingApp, bd)
        end

        % Button pushed function: ApplytocurrentButton
        function ApplytocurrentButtonPushed(app, event)
            Spiculation_gen(app,false)
            bd = evalin("base","bd");
            updateimage(app.CallingApp, bd)
        end

        % Close request function: BoundarySpiculationUIFigure
        function BoundarySpiculationUIFigureCloseRequest(app, event)
            delete(app)

        end

        % Button pushed function: UndoonceButton
        function UndoonceButtonPushed(app, event)
            % Restore last-time "bd" if it exists
            try
                bd = evalin("base","old_bd");
                assignin("base","bd",bd); % public data
                updateimage(app.CallingApp, bd)
                app.UndoonceButton.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BoundarySpiculationUIFigure = uifigure('Visible', 'off');
            app.BoundarySpiculationUIFigure.Name = 'Boundary Spiculation';
            app.BoundarySpiculationUIFigure.CloseRequestFcn = createCallbackFcn(app, @BoundarySpiculationUIFigureCloseRequest, true);
            app.center_value = uieditfield(app.BoundarySpiculationUIFigure, 'numeric');
            app.center_value.Limits = [0 Inf];
            app.center_value.ValueChangedFcn = createCallbackFcn(app, @center_valueValueChanged, true);
            app.center_value.Value = 35;
            app.CenterSliderLabel = uilabel(app.BoundarySpiculationUIFigure);
            app.CenterSliderLabel.Text = 'Center';
            app.CenterSlider = uislider(app.BoundarySpiculationUIFigure);
            app.CenterSlider.ValueChangedFcn = createCallbackFcn(app, @CenterSliderValueChanged, true);
            app.CenterSlider.ValueChangingFcn = createCallbackFcn(app, @CenterSliderValueChanging, true);
            app.Button = uibutton(app.BoundarySpiculationUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Close';
            app.ApplytooriginalButton = uibutton(app.BoundarySpiculationUIFigure, 'push');
            app.ApplytooriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytooriginalButtonPushed, true);
            app.ApplytooriginalButton.Text = 'Apply to original';
            app.ApplytocurrentButton = uibutton(app.BoundarySpiculationUIFigure, 'push');
            app.ApplytocurrentButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytocurrentButtonPushed, true);
            app.ApplytocurrentButton.Text = '*Apply to current';
            app.WidthEditFieldLabel = uilabel(app.BoundarySpiculationUIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.BoundarySpiculationUIFigure, 'text');
            app.WidthEditField.Value = '10';
            app.HeightEditFieldLabel = uilabel(app.BoundarySpiculationUIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.BoundarySpiculationUIFigure, 'text');
            app.HeightEditField.Value = '50';
            app.UndoonceButton = uibutton(app.BoundarySpiculationUIFigure, 'push');
            app.UndoonceButton.ButtonPushedFcn = createCallbackFcn(app, @UndoonceButtonPushed, true);
            app.UndoonceButton.Text = 'Undo once';
            % Show the figure after all components are created
            app.BoundarySpiculationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spiculation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BoundarySpiculationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BoundarySpiculationUIFigure)
        end
    end
end