classdef synmask_win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveButton                      matlab.ui.control.Button
        TruthMaskLabel                  matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        ReturntomainwindowButton        matlab.ui.control.Button
        ModeLabel                       matlab.ui.control.Label
        ComputeHausdorffCheckBox        matlab.ui.control.CheckBox
        SpiculationButton               matlab.ui.control.Button
        AffineButton                    matlab.ui.control.Button
        FourierButton                   matlab.ui.control.Button
        SegmentationMetricsListBoxLabel  matlab.ui.control.Label
        SegmentationMetricsListBox      matlab.ui.control.ListBox
        LoadButton                      matlab.ui.control.Button
        DisplayinfilledcontourCheckBox  matlab.ui.control.CheckBox
        UserInputPanel                  matlab.ui.container.Panel
        SegmentationcontourButton       matlab.ui.control.Button
        SegFileLabel                    matlab.ui.control.Label
        ReferencecontourButton          matlab.ui.control.Button
        TruthFileLabel                  matlab.ui.control.Label
        ImageAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        DialogApp                   % Dialog box app
        CallingApp
        CurrentSize = 35;           % Surface sample size
        CurrentColormap = 'Parula'; % Colormap
    end

    methods (Access = private)

        % load Truth Mask
        function flag_res = load_mask (~, imagefile)
            im = imread(imagefile);
            if size(im,3)~=1
                im=rgb2gray(im);
            end
            omask = imbinarize(im);  % binary area image
            assignin("base","omask",omask); % public data: original mask

            [B, ~]  = bwboundaries(omask);
            if length(B)>1
                flag_res = false;
            else
                obd = B{1}; % take the first boundary
                assignin("base","obd",obd); % public data: original boundry
                flag_res = true;
            end

        end

        % load Segmentation contour
        function flag_res = input_seg (~, imagefile)
            im = imread(imagefile);
            if size(im,3)~=1
                im=rgb2gray(im);
            end
            
            mask = imbinarize(im);  % binary area image
            assignin("base","oseg",mask); % public data: orginal seg
            
            [B, ~]  = bwboundaries(mask);
            
            if length(B)>1
                flag_res = false;
            else
                bd = B{1}; % take the first boundary
                assignin("base","bd",bd); % public data: modified boundry
                flag_res = true;
            end

        end
        
        % load Reference contour
        function flag_res = input_truth (~, imagefile)
            im = imread(imagefile);
            if size(im,3)~=1
                im=rgb2gray(im);
            end
            omask = imbinarize(im);  % binary area image
            assignin("base","omask",omask); % public data: original mask

            [B, ~]  = bwboundaries(omask);
            if length(B)>1
                flag_res = false;
            else
                obd = B{1}; % take the first boundary
                assignin("base","obd",obd); % public data: original boundry
                flag_res = true;
            end

        end


    end


    methods (Access = public)

        % Update showing plots and metrics values
        function updateimage(app, boundary)
            omask = evalin("base","omask"); % Original mask
            obd = evalin("base","obd"); % Original boundary (contour)
            
            % set color map for drawing contours/masks
            cmap = [0, 0, 0; ...   % Black for 0: background
                1, 0, 0; ...    % Blue for 1: orginal input
                0, 0, 1];       % Red for 2: synthetic
            colormap(app.ImageAxes,cmap);

            try
                % ############### Draw contours/masks ################
                imagesc(app.ImageAxes,uint8(omask));

                % plot the boundary
                hold( app.ImageAxes, 'on' )
                plot(app.ImageAxes,boundary(:,2), boundary(:,1), 'g', 'LineWidth', 1);

                % convert boundary to mask
                filled = poly2mask(boundary(:,2), boundary(:,1),size(omask,1),size(omask,2));

                % plot filled contours
                if app.DisplayinfilledcontourCheckBox.Value==true
                    % Try to change the color (red)
                    filled = uint8(filled);
                    filled(filled==1) = 2;
                    h = imagesc(app.ImageAxes,filled);
                    h.AlphaData = 0.5;
                    hold( app.ImageAxes, 'off' )
                end
                % ######################################################
                
                % for original inputs in syn mode, no change -> filled = omask
                if isequal(boundary,obd) 
                    filled = omask; % use original mask to compute metrics
                    %                     dice_value=1;
                    %                     FN = 0; FP = 0;
                    %                     jacc=1;
                end

                % in metrics mode, no change -> filled = oseg
                if app.ModeLabel.Text == "Metrics Computation" 
                    oseg = evalin("base","oseg");
                    filled = oseg; % use original seg to compute metrics
                end
                
                assignin("base","filled",filled); % generated mask (filled modified contour)
                
                % ############### Segmentation Evaluation ################
                dice_value = dice(omask,filled);
                jacc = jaccard(omask,filled);

                % medical similarity index (MSI)
                % by default = MSI(ref_img,test_img,1,1);
                MSI_value = MSI(omask,filled);

                confusionMatrix = segmentationConfusionMatrix(filled,omask);
                TN = confusionMatrix(1,1); TP = confusionMatrix(2,2);
                FN = confusionMatrix(2,1); FP = confusionMatrix(1,2);
                N = TP + FP + TN + FN; % the total number

                % Compute Hausdorff Distances (slower)
                f = waitbar(0,'Computing measures...');
                if app.ComputeHausdorffCheckBox.Value==true
                    waitbar(0,f,'Computing Hausdorff Distance...');
                    dh = hausdorff(omask,filled);
                    waitbar(0.5,f,'Computing Average Hausdorff Distance...');
                    avd = AVD(omask,filled);
                    waitbar(0.9,f,'Computing other measures...');
                else
                    dh = NaN;
                    avd = NaN;
                end

                acu = 1-0.5*(FP/(FP+TN)+FN/(FN+TP));
                vs = 1-abs(FN-FP)/(2*TP + FN + FP);
                kap = KAP(TP, FP, TN, FN); % Cohen Kappa
                ari = ARI(TP, FP, TN, FN);
                icc = ICC(omask,filled);
                pd=PBD(omask,filled);
                mhd = MHD(omask,filled);
                mi=MI(N,TP,FP,FN,TN);
                voi=VOI(N,TP,FP,FN,TN);
                gce=GCE(TP,FP,FN,TN);

                close(f)

                app.SegmentationMetricsListBox.Items = {['Dice: ',num2str(dice_value)], ['Jaccard: ',num2str(jacc)],...
                    ['MSI: ',num2str(MSI_value)], ...
                    ['FN: ',num2str(FN)],['FP: ',num2str(FP)],['TN: ',num2str(TN)],['TP: ',num2str(TP)],...
                    ['Recall/Sensitivity (TPR): ',num2str(TP/(TP+FN))],['Specificity (TNR): ',num2str(TN/(TN+FP))],...
                    ['Fallout (FPR): ',num2str(FP/(FP+TN))],...
                    ['Precision (PPV): ',num2str(TP/(TP+FP))],['Accuracy (ACC): ',num2str((TP+TN)/N)],...
                    ['AUC: ',num2str(acu)],['KAP: ',num2str(kap)],...
                    ['ARI: ',num2str(ari)],['ICC: ',num2str(icc)],['VS: ',num2str(vs)],...
                    ['MI: ',num2str(mi)],['VOI: ',num2str(voi)],['PBD: ',num2str(pd)],...
                    ['GCE: ',num2str(gce)],['HD: ',num2str(dh)],['AVD: ',num2str(avd)],...
                    ['MHD: ',num2str(mhd)]};
                
                metrics_out = struct('Dice',num2str(dice_value), 'Jaccard',num2str(jacc),...
                    'MSI',num2str(MSI_value), ...
                    'FN',num2str(FN),'FP',num2str(FP),'TN',num2str(TN),'TP',num2str(TP),...
                    'Recall_Sensitivity_TPR',num2str(TP/(TP+FN)),'Specificity_TNR',num2str(TN/(TN+FP)),...
                    'Fallout_FPR',num2str(FP/(FP+TN)),...
                    'Precision_PPV',num2str(TP/(TP+FP)),'Accuracy_ACC',num2str((TP+TN)/N),...
                    'AUC',num2str(acu),'KAP',num2str(kap),...
                    'ARI',num2str(ari),'ICC',num2str(icc),'VS',num2str(vs),...
                    'MI',num2str(mi),'VOI',num2str(voi),'PBD',num2str(pd),...
                    'GCE',num2str(gce),'HD',num2str(dh),'AVD',num2str(avd),...
                    'MHD',num2str(mhd));
                
                assignin("base","metrics_value",metrics_out); % output data

               % ################################################

            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, mode)
            app.CallingApp = mainapp;
            app.ModeLabel.Text = mode;

            % Configure image axes
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');

            % Two modes
            if mode=="Synthesis of Contour"
                app.SegmentationcontourButton.Visible=false;
                app.ReferencecontourButton.Visible=false;
                app.SegFileLabel.Visible=false;
                app.TruthFileLabel.Visible=false;
                app.UserInputPanel.Visible = false;
                app.LoadButton.Visible=true;
                app.SpiculationButton.Visible=true;
                app.FourierButton.Visible=true;
                app.AffineButton.Visible=true;
                app.TruthMaskLabel.Visible = true;
                app.TruthMaskLabel.Text = "";
                app.SaveButton.Text = "Save syn mask & metrics values";

            elseif mode=="Metrics Computation"
                app.SegmentationcontourButton.Visible=true;
                app.ReferencecontourButton.Visible=true;
                app.SegFileLabel.Visible=true;
                app.TruthFileLabel.Visible=true;
                app.SegFileLabel.Text="";
                app.TruthFileLabel.Text="";
                app.UserInputPanel.Visible = true;

                app.LoadButton.Visible=false;
                app.SpiculationButton.Visible=false;
                app.FourierButton.Visible=false;
                app.AffineButton.Visible=false;
                app.TruthMaskLabel.Visible = false;
                app.SaveButton.Text = "Save metrics values";
               
            end

        end

        % Callback function
        function DropDownValueChanged(app, event)

            % Update the image and histograms
            %             updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                try
                    fname = [p f];
                    flag_res = load_mask(app, fname);

                    if flag_res==true
                        app.TruthMaskLabel.Text = f;
                        % if app.TruthMaskLabel.Text ~= ""
                        bd = evalin("base","obd");
                        assignin("base","bd",bd); % public data
                        updateimage(app, bd)
                        % end
                    else
                        uialert(app.UIFigure,'Recent can handle only one individual component!','Function Warning',"CloseFcn",@(h,e)app.LoadButtonPushed)
                    end
                catch ME
                    % uialert(app.UIFigure, ME.message, 'Image Error');
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: DisplayinfilledcontourCheckBox
        function DisplayinfilledcontourCheckBoxValueChanged(app, event)
            bd = evalin("base","bd");
            updateimage(app, bd)
        end

        % Button pushed function: FourierButton
        function FourierButtonPushed(app, event)
            omask = evalin("base","omask");
            assignin("base","omask",omask);

            % Disable Plot Options button while dialog is open
            % app.UIFigure.Visible = 'off';

            % Open the options dialog and pass inputs
            app.DialogApp = Fourier(app);
        end

        % Button pushed function: AffineButton
        function AffineButtonPushed(app, event)
            app.DialogApp = Affine(app);
        end

        % Button pushed function: SpiculationButton
        function SpiculationButtonPushed(app, event)
            app.DialogApp = Spiculation(app);
        end

        % Value changed function: ComputeHausdorffCheckBox
        function ComputeHausdorffCheckBoxValueChanged(app, event)
            bd = evalin("base","bd"); % modified boundry
            updateimage(app, bd)
        end

        % Button pushed function: SegmentationcontourButton
        function SegmentationcontourButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                try
                    fname = [p f];
                    flag_res = input_seg(app, fname);
                             
                    if flag_res==true
                        app.SegFileLabel.Text=f;

                        if app.SegFileLabel.Text ~= "" && app.TruthFileLabel.Text ~=""
                            bd = evalin("base","bd"); % modified boundry
                            updateimage(app, bd)
                        end
                    else
                        uialert(app.UIFigure,'Recent can handle only one individual component!','Function Warning',"CloseFcn",@(h,e)app.LoadButtonPushed)
                    end
                catch ME
                    % uialert(app.UIFigure, ME.message, 'Image Error');
                end
            end
        end

        % Button pushed function: ReferencecontourButton
        function ReferencecontourButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                try
                    fname = [p f];
                    flag_res = input_truth(app, fname);

                    if flag_res==true
                        app.TruthFileLabel.Text=f;

                        if app.SegFileLabel.Text ~= "" && app.TruthFileLabel.Text ~=""
                            bd = evalin("base","bd"); % modified boundry
                            updateimage(app, bd)
                        end
                    else
                        uialert(app.UIFigure,'Recent can handle only one individual component!','Function Warning',"CloseFcn",@(h,e)app.LoadButtonPushed)
                    end
                catch ME
                    % uialert(app.UIFigure, ME.message, 'Image Error');
                end
            end
        end

        % Button pushed function: ReturntomainwindowButton
        function ReturntomainwindowButtonPushed(app, event)
            app.DialogApp = main_option();
            delete(app)
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            % Open help doc (htm)
            web("User Guide.htm#main-opt")
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            metrics_value = evalin("base","metrics_value");
            filled = evalin("base","filled");

            % Save syn seg and metrics values
            switch app.ModeLabel.Text
                case "Metrics Computation" % Metric mode
                    writetable(struct2table(metrics_value), ...
                        ['Saved/',app.SegFileLabel.Text(1:end-4),'_Metrics.csv']);


                case "Synthesis of Contour" % Syn mode
                    writetable(struct2table(metrics_value), ...
                        ['Saved/','Syn_',app.TruthMaskLabel.Text(1:end-4),'_Metrics.csv']);
                    imwrite(filled,['Saved/','Syn_',app.TruthMaskLabel.Text(1:end-4),'.png'],"png");

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Segmentation Viewer';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.UserInputPanel = uipanel(app.UIFigure);
            app.UserInputPanel.Title = 'User Input';
            app.TruthFileLabel = uilabel(app.UserInputPanel);
            app.TruthFileLabel.Text = 'Truth File';
            app.ReferencecontourButton = uibutton(app.UserInputPanel, 'push');
            app.ReferencecontourButton.ButtonPushedFcn = createCallbackFcn(app, @ReferencecontourButtonPushed, true);
            app.ReferencecontourButton.Text = 'Reference contour';
            app.SegFileLabel = uilabel(app.UserInputPanel);
            app.SegFileLabel.Text = 'Seg File';
            app.SegmentationcontourButton = uibutton(app.UserInputPanel, 'push');
            app.SegmentationcontourButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationcontourButtonPushed, true);
            app.SegmentationcontourButton.Text = 'Segmentation contour';
            app.DisplayinfilledcontourCheckBox = uicheckbox(app.UIFigure);
            app.DisplayinfilledcontourCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayinfilledcontourCheckBoxValueChanged, true);
            app.DisplayinfilledcontourCheckBox.Text = 'Display in filled contour';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Truth Mask';
            app.SegmentationMetricsListBox = uilistbox(app.UIFigure);
            app.SegmentationMetricsListBox.Items = {};
            app.SegmentationMetricsListBox.Value = {};
            app.SegmentationMetricsListBoxLabel = uilabel(app.UIFigure);
            app.SegmentationMetricsListBoxLabel.Text = 'Segmentation Metrics';
            app.FourierButton = uibutton(app.UIFigure, 'push');
            app.FourierButton.ButtonPushedFcn = createCallbackFcn(app, @FourierButtonPushed, true);
            app.FourierButton.Text = 'Fourier';
            app.AffineButton = uibutton(app.UIFigure, 'push');
            app.AffineButton.ButtonPushedFcn = createCallbackFcn(app, @AffineButtonPushed, true);
            app.AffineButton.Text = 'Affine';
            app.SpiculationButton = uibutton(app.UIFigure, 'push');
            app.SpiculationButton.ButtonPushedFcn = createCallbackFcn(app, @SpiculationButtonPushed, true);
            app.SpiculationButton.Text = 'Spiculation';
            app.ComputeHausdorffCheckBox = uicheckbox(app.UIFigure);
            app.ComputeHausdorffCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputeHausdorffCheckBoxValueChanged, true);
            app.ComputeHausdorffCheckBox.Text = {'Compute Hausdorff '; 'Distances (slower)'};
            app.ModeLabel = uilabel(app.UIFigure);
            app.ModeLabel.Text = 'Mode';
            app.ReturntomainwindowButton = uibutton(app.UIFigure, 'push');
            app.ReturntomainwindowButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntomainwindowButtonPushed, true);
            app.ReturntomainwindowButton.Text = 'Return to main window';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.TruthMaskLabel = uilabel(app.UIFigure);
            app.TruthMaskLabel.Text = 'TruthMask';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synmask_win(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end